classdef newtonRaphson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        aTextArea                       matlab.ui.control.TextArea
        aTextAreaLabel                  matlab.ui.control.Label
        DTextArea                       matlab.ui.control.TextArea
        DTextAreaLabel                  matlab.ui.control.Label
        alphaTextArea                   matlab.ui.control.TextArea
        alphaTextAreaLabel              matlab.ui.control.Label
        jointnumbersEditField           matlab.ui.control.NumericEditField
        jointnumbersEditFieldLabel      matlab.ui.control.Label
        thevaluesofjointsTextArea       matlab.ui.control.TextArea
        thevaluesofjointsTextAreaLabel  matlab.ui.control.Label
        OutputLabel                     matlab.ui.control.Label
        DisplaytheTextArea              matlab.ui.control.TextArea
        CalculateInverseKinematicsButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateInverseKinematicsButton
        function inversekinematicsButtonPushed(app, event)


    % Step (a) Define DH parameters and forward kinematics
    n = (app.jointnumbersEditField.Value);  % Number of joints
    
    % Define joint variables dynamically
    q = sym('q', [1 n]);  % Creates a symbolic row vector for joint variables
    
    alpha = app.alphaTextArea.Value;
    alpha = deg2rad(str2num(alpha{1}));  % Convert to radians
    a = str2num(app.aTextArea.Value{1});
    d = str2num(app.DTextArea.Value{1});
    
    % Define transformation matrices based on DH parameters
    T = eye(4); % Start with identity matrix
    for i = 1:n
        T = T * dh_to_transform(alpha(i), d(i), a(i), q(i));
    end
    
    % Compute forward kinematics
    T_final = simplify(T);
    
    % Step (a) Find desired configuration matrix {Tdes}
    q_vals = deg2rad(str2num(app.thevaluesofjointsTextArea.Value{1}));
    T_des = double(subs(T_final, q, q_vals));
    
    % Initialize output string for the app's text area
    outputString = "Inverse Kinematics Results:\n";
    
    % Step (b) Newton-Raphson method to find multiple solutions
    initial_guesses = [
        0.1*ones(1, n);
        -0.1*ones(1, n);
        pi/4*ones(1, n);
        -pi/4*ones(1, n);
        zeros(1, n);
        0.2*ones(1, n);
        -0.2*ones(1, n);
        ones(1, n);
        -ones(1, n);
        2*ones(1, n);
        -2*ones(1, n)
    ]; % Add more initial guesses as needed
    
    solutions_square = []; % Store all solutions
    solutions_rectangular = [];
    
    % Define the error function
    T_finalE = T_final(1:3, 1:4);
    T_desE = T_des(1:3, 1:4);
    error_func = @(k) reshape(double(subs(T_finalE - T_desE, q, k)), [], 1);
    
    % Compute the Jacobian matrix
    J = jacobian([reshape(T_final(1:3, 1:3), [], 1); T_final(1:3, 4)], q);
    
    % Square Jacobian Method
    outputString = outputString + "Square Jacobian Method:\n";
    for guess_idx = 1:size(initial_guesses, 1)
        q_initial = initial_guesses(guess_idx, :);
        q_solution_square = q_initial;
        for iter = 1:100  % Maximum iterations
            current_error_square = error_func(q_solution_square);
            current_J_square = double(subs(J, q, num2cell(q_solution_square)));
            if norm(current_error_square) < .00005
                outputString = outputString + sprintf("Converged for initial guess %d in %d iterations.\n", guess_idx, iter);
                break;
            end
            delta_q = -current_J_square \ current_error_square;
            q_solution_square = q_solution_square + delta_q';
        end
        if iter < 100 && all(abs(current_error_square) < 1e-6)
            is_unique = true;
            for s = 1:size(solutions_square, 1)
                if norm(mod(solutions_square(s, :), 6.28) - mod(q_solution_square, 6.28)) < 0.1
                    is_unique = false;
                    break;
                end
            end
            if is_unique
                solutions_square = [solutions_square; q_solution_square]; %#ok<AGROW>
            end
        end
    end
    
    solutions_deg_square = rad2deg(solutions_square);
    outputString = outputString + "All unique joint solutions (in degrees):\n";
    outputString = outputString + sprintf("%s\n", mat2str(mod(solutions_deg_square, 360)));
    
    % Rectangular Jacobian Method
    outputString = outputString + "Rectangular Jacobian Method:\n";
    for guess_idx = 1:size(initial_guesses, 1)
        q_initial = initial_guesses(guess_idx, :);
        q_solution_rectangular = q_initial;
        for iter = 1:100
            current_error_rectangular = error_func(q_solution_rectangular);
            current_J_rectangular = double(subs(J, q, num2cell(q_solution_rectangular)));
            if norm(current_error_rectangular) < .00005
                outputString = outputString + sprintf("Converged for initial guess %d in %d iterations.\n", guess_idx, iter);
                break;
            end
            delta_q = -(current_J_rectangular' * current_J_rectangular) \ (current_J_rectangular' * current_error_rectangular);
            q_solution_rectangular = q_solution_rectangular + delta_q';
        end
        if iter < 100 && all(abs(current_error_rectangular) < 1e-6)
            is_unique = true;
            for s = 1:size(solutions_rectangular, 1)
                if norm(mod(solutions_rectangular(s, :), 6.28) - mod(q_solution_rectangular, 6.28)) < 0.1
                    is_unique = false;
                    break;
                end
            end
            if is_unique
                solutions_rectangular = [solutions_rectangular; q_solution_rectangular]; %#ok<AGROW>
            end
        end
    end
    
    solutions_deg_rectangular = rad2deg(solutions_rectangular);
    outputString = outputString + "All unique joint solutions (in degrees):\n";
    outputString = outputString + sprintf("%s\n", mat2str(mod(solutions_deg_rectangular, 360)));
    
    % Display results in the text area
    app.DisplaytheTextArea.Value = outputString;

function T = dh_to_transform(alpha, d, a, theta)
    T = [
        cos(theta), -sin(theta), 0, a;
        sin(theta) * cos(alpha), cos(theta) * cos(alpha), -sin(alpha), -sin(alpha)*d;
        sin(theta) * sin(alpha), sin(alpha) * cos(theta), cos(alpha),cos(alpha)* d;
        0, 0, 0, 1
    ];
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateInverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.CalculateInverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @inversekinematicsButtonPushed, true);
            app.CalculateInverseKinematicsButton.Text = 'Calculate Inverse Kinematics';
            app.DisplaytheTextArea = uitextarea(app.UIFigure);
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.thevaluesofjointsTextAreaLabel = uilabel(app.UIFigure);
            app.thevaluesofjointsTextAreaLabel.Text = 'the values of joints';
            app.thevaluesofjointsTextArea = uitextarea(app.UIFigure);
            app.thevaluesofjointsTextArea.Value = {'[130.37 -24.649 76.272 128.38 49.635]'};
            app.jointnumbersEditFieldLabel = uilabel(app.UIFigure);
            app.jointnumbersEditFieldLabel.Text = 'joint numbers';
            app.jointnumbersEditField = uieditfield(app.UIFigure, 'numeric');
            app.jointnumbersEditField.Value = 5;
            app.alphaTextAreaLabel = uilabel(app.UIFigure);
            app.alphaTextAreaLabel.Text = 'alpha';
            app.alphaTextArea = uitextarea(app.UIFigure);
            app.alphaTextArea.Value = {'[0 90 0 0 90]'};
            app.DTextAreaLabel = uilabel(app.UIFigure);
            app.DTextAreaLabel.Text = 'D';
            app.DTextArea = uitextarea(app.UIFigure);
            app.DTextArea.Value = {'[2.75 0 0 0 0]'};
            app.aTextAreaLabel = uilabel(app.UIFigure);
            app.aTextAreaLabel.Text = 'a';
            app.aTextArea = uitextarea(app.UIFigure);
            app.aTextArea.Value = {'[2.7 0 2 1.3 0]'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newtonRaphson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end