classdef piper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OutputLabel                matlab.ui.control.Label
        D6FieldEditField           matlab.ui.control.NumericEditField
        D6FieldEditFieldLabel      matlab.ui.control.Label
        EnterthevaluesoftheDHparametersanglesindegreeLabel  matlab.ui.control.Label
        A5FieldEditField           matlab.ui.control.NumericEditField
        A5FieldEditFieldLabel      matlab.ui.control.Label
        DisplaytheTextArea         matlab.ui.control.TextArea
        DisplaytheTextAreaLabel    matlab.ui.control.Label
        CalculateInverseKinematicsButton  matlab.ui.control.Button
        CalculateForwardKinematicsButton  matlab.ui.control.Button
        A0FieldEditField           matlab.ui.control.NumericEditField
        A0FieldEditFieldLabel      matlab.ui.control.Label
        A4FieldEditField           matlab.ui.control.NumericEditField
        A4FieldEditFieldLabel      matlab.ui.control.Label
        D2FieldEditField           matlab.ui.control.NumericEditField
        D2FieldEditFieldLabel      matlab.ui.control.Label
        Alpha4FieldEditField       matlab.ui.control.NumericEditField
        Alpha4FieldEditFieldLabel  matlab.ui.control.Label
        D4FieldEditField           matlab.ui.control.NumericEditField
        D4FieldEditFieldLabel      matlab.ui.control.Label
        Alpha3FieldEditField       matlab.ui.control.NumericEditField
        Alpha3FieldEditFieldLabel  matlab.ui.control.Label
        A2FieldEditField           matlab.ui.control.NumericEditField
        A2FieldEditFieldLabel      matlab.ui.control.Label
        D1FieldEditField           matlab.ui.control.NumericEditField
        D1FieldEditFieldLabel      matlab.ui.control.Label
        Alpha5FieldEditField       matlab.ui.control.NumericEditField
        Alpha5FieldEditFieldLabel  matlab.ui.control.Label
        D5FieldEditField           matlab.ui.control.NumericEditField
        D5FieldEditFieldLabel      matlab.ui.control.Label
        A3FieldEditField           matlab.ui.control.NumericEditField
        A3FieldEditFieldLabel      matlab.ui.control.Label
        D3FieldEditField           matlab.ui.control.NumericEditField
        D3FieldEditFieldLabel      matlab.ui.control.Label
        Alpha2FieldEditField       matlab.ui.control.NumericEditField
        Alpha2FieldEditFieldLabel  matlab.ui.control.Label
        A1FieldEditField           matlab.ui.control.NumericEditField
        A1FieldEditFieldLabel      matlab.ui.control.Label
        Alpha1FieldEditField       matlab.ui.control.NumericEditField
        Alpha1FieldEditFieldLabel  matlab.ui.control.Label
        Alpha0FieldEditField       matlab.ui.control.NumericEditField
        Alpha0FieldEditFieldLabel  matlab.ui.control.Label
        EnterthevaluesofthejointvariblesindegreeLabel  matlab.ui.control.Label
        Theta6FieldEditField       matlab.ui.control.NumericEditField
        Theta6FieldEditFieldLabel  matlab.ui.control.Label
        Theta5FieldEditField       matlab.ui.control.NumericEditField
        Theta5FieldEditFieldLabel  matlab.ui.control.Label
        Theta4FieldEditField       matlab.ui.control.NumericEditField
        Theta4FieldEditFieldLabel  matlab.ui.control.Label
        Theta3FieldEditField       matlab.ui.control.NumericEditField
        Theta3FieldEditFieldLabel  matlab.ui.control.Label
        Theta2FieldEditField       matlab.ui.control.NumericEditField
        Theta2FieldEditFieldLabel  matlab.ui.control.Label
        Theta1FieldEditField       matlab.ui.control.NumericEditField
        Theta1FieldEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateForwardKinematicsButton
        function CalculateForwardKinematicsButtonPushed(app, event)
            % --- Executes on button press: Calculate Forward Kinematics

    % Get the joint angles from the input fields
    theta1val = deg2rad(app.Theta1FieldEditField.Value); % Convert to radians
    theta2val = deg2rad(app.Theta2FieldEditField.Value);
    theta3val = deg2rad(app.Theta3FieldEditField.Value);
    theta4val = deg2rad(app.Theta4FieldEditField.Value);
    theta5val = deg2rad(app.Theta5FieldEditField.Value);
    theta6val = deg2rad(app.Theta6FieldEditField.Value);
    theta = [theta1val, theta2val, theta3val, theta4val, theta5val, theta6val];
    
    % Get the DH parameters from the input fields
    d = [app.D1FieldEditField.Value, app.D2FieldEditField.Value, app.D3FieldEditField.Value, ...
         app.D4FieldEditField.Value, app.D5FieldEditField.Value, app.D6FieldEditField.Value];
    a = [app.A0FieldEditField.Value, app.A1FieldEditField.Value, app.A2FieldEditField.Value, ...
         app.A3FieldEditField.Value, app.A4FieldEditField.Value, app.A5FieldEditField.Value];
    alpha_deg = [app.Alpha0FieldEditField.Value, app.Alpha1FieldEditField.Value, app.Alpha2FieldEditField.Value, ...
                 app.Alpha3FieldEditField.Value, app.Alpha4FieldEditField.Value, app.Alpha5FieldEditField.Value];
    alpha = deg2rad(alpha_deg); % Convert to radians

    % Perform Forward Kinematics
    T = eye(4); % Initialize transformation matrix
    for i = 1:6
        Ti = [
            cos(theta(i)), -sin(theta(i)), 0, a(i);
            sin(theta(i)) * cos(alpha(i)), cos(theta(i)) * cos(alpha(i)), -sin(alpha(i)), -sin(alpha(i))*d(i);
            sin(theta(i)) * sin(alpha(i)), sin(alpha(i)) * cos(theta(i)), cos(alpha(i)), cos(alpha(i))*d(i);
            0, 0, 0, 1;
        ];
        T = T * Ti; % Multiply the transformations
    end

    % Display the result
    app.DisplaytheTextArea.Value = sprintf('Forward Kinematics (T):\n%s', mat2str(T, 4));

        end

        % Button pushed function: CalculateInverseKinematicsButton
        function CalculateInverseKinematicsButtonPushed(app, event)
            % --- Executes on button press: Calculate Inverse Kinematics

    theta= sym('theta', [1 6]);
    % Get the joint angles from the input fields
    theta1val = deg2rad(app.Theta1FieldEditField.Value); % Convert to radians
    theta2val = deg2rad(app.Theta2FieldEditField.Value);
    theta3val = deg2rad(app.Theta3FieldEditField.Value);
    theta4val = deg2rad(app.Theta4FieldEditField.Value);
    theta5val = deg2rad(app.Theta5FieldEditField.Value);
    theta6val = deg2rad(app.Theta6FieldEditField.Value);
    thetas = [theta1val, theta2val, theta3val, theta4val, theta5val, theta6val];
    
    % Get the DH parameters from the input fields
    d = [app.D1FieldEditField.Value, app.D2FieldEditField.Value, app.D3FieldEditField.Value, ...
         app.D4FieldEditField.Value, app.D5FieldEditField.Value, app.D6FieldEditField.Value];
    a = [app.A0FieldEditField.Value, app.A1FieldEditField.Value, app.A2FieldEditField.Value, ...
         app.A3FieldEditField.Value, app.A4FieldEditField.Value, app.A5FieldEditField.Value];
    alpha_deg = [app.Alpha0FieldEditField.Value, app.Alpha1FieldEditField.Value, app.Alpha2FieldEditField.Value, ...
                 app.Alpha3FieldEditField.Value, app.Alpha4FieldEditField.Value, app.Alpha5FieldEditField.Value];
    alpha = deg2rad(alpha_deg); % Convert to radians

    % Perform Forward Kinematics
    T = eye(4); % Initialize transformation matrix
    for i = 1:6
        Ti = [
            cos(thetas(i)), -sin(thetas(i)), 0, a(i);
            sin(thetas(i)) * cos(alpha(i)), cos(thetas(i)) * cos(alpha(i)), -sin(alpha(i)), -sin(alpha(i))*d(i);
            sin(thetas(i)) * sin(alpha(i)), sin(alpha(i)) * cos(thetas(i)), cos(alpha(i)), cos(alpha(i))*d(i);
            0, 0, 0, 1;
        ];
        T = T * Ti; % Multiply the transformations
    end
    % Get the desired position (Px, Py, Pz) T matrix
    px_eq = T(1,4);
    py_eq = T(2,4);
    pz_eq = T(3,4);
 
% Solving for theta2 and theta3
f1 = (app.A2FieldEditField.Value) +(app.A3FieldEditField.Value*cosd(theta(3))) +(app.D4FieldEditField.Value)*sind(app.Alpha3FieldEditField.Value)*sind(theta(3)); 
f2 = app.A3FieldEditField.Value*cosd(app.Alpha2FieldEditField.Value)*sind(theta(3)) - app.D4FieldEditField.Value*cosd(app.Alpha3FieldEditField.Value)*sind(app.Alpha2FieldEditField.Value) - app.D3FieldEditField.Value*sind(app.Alpha2FieldEditField.Value) ...
    - app.D4FieldEditField.Value*cosd(app.Alpha2FieldEditField.Value)*sind(app.Alpha3FieldEditField.Value)*cosd(theta(3));
f3 = app.D3FieldEditField.Value*cosd(app.Alpha2FieldEditField.Value) + app.D4FieldEditField.Value*cosd(app.Alpha2FieldEditField.Value)*cosd(app.Alpha3FieldEditField.Value) + app.A3FieldEditField.Value*sind(app.Alpha2FieldEditField.Value)*sind(theta(3)) ...
    - app.D4FieldEditField.Value*sind(app.Alpha2FieldEditField.Value)*sind(app.Alpha3FieldEditField.Value)*cosd(theta(3));

k1 = f1;
k2 = -f2;
k3 = f1^2 + f2^2 + f3^2 + 2 * app.D2FieldEditField.Value * f3 + app.A1FieldEditField.Value^2 + app.D2FieldEditField.Value^2;
k4 = f3 * cosd(app.Alpha1FieldEditField.Value) + app.D2FieldEditField.Value * cosd(app.Alpha1FieldEditField.Value);
if(app.A1FieldEditField.Value == 0)
% Calculate the coefficients for the quadratic equation
A = app.A1FieldEditField.Value^2 - 2 * app.A2FieldEditField.Value * app.A3FieldEditField.Value + app.A2FieldEditField.Value^2 + app.A3FieldEditField.Value^2 ...
    + app.D2FieldEditField.Value^2 + app.D3FieldEditField.Value^2 + app.D4FieldEditField.Value^2 ...
    + 2 * app.D2FieldEditField.Value * app.D3FieldEditField.Value * cosd(app.Alpha2FieldEditField.Value) ...
    + 2 * app.D3FieldEditField.Value * app.D4FieldEditField.Value * cosd(app.Alpha3FieldEditField.Value) ...
    + 2 * app.D2FieldEditField.Value * app.D4FieldEditField.Value * cosd(app.Alpha2FieldEditField.Value - app.Alpha3FieldEditField.Value) ...
    - (px_eq^2 + py_eq^2 + pz_eq^2);

B = 4 * app.A3FieldEditField.Value * app.D2FieldEditField.Value * sind(app.Alpha2FieldEditField.Value) ...
    + 4 * app.A2FieldEditField.Value * app.D4FieldEditField.Value * sind(app.Alpha3FieldEditField.Value);

C = 2 * app.A2FieldEditField.Value * app.A3FieldEditField.Value + app.A1FieldEditField.Value^2 + app.A2FieldEditField.Value^2 + app.A3FieldEditField.Value^2 ...
    + app.D2FieldEditField.Value^2 + app.D3FieldEditField.Value^2 + app.D4FieldEditField.Value^2 ...
    + 2 * app.D2FieldEditField.Value * app.D3FieldEditField.Value * cosd(app.Alpha2FieldEditField.Value) ...
    + 2 * app.D3FieldEditField.Value * app.D4FieldEditField.Value * cosd(app.Alpha3FieldEditField.Value) ...
    + 2 * app.D2FieldEditField.Value * app.D4FieldEditField.Value * cosd(app.Alpha2FieldEditField.Value + app.Alpha3FieldEditField.Value) ...
    - (px_eq^2 + py_eq^2 + pz_eq^2);

syms u
quadratic_eq = (u^2)*A + u*B + C == 0;

% Solve the equation
u_solutions = solve(quadratic_eq, u);

% Iterate through the solutions for u
for i = 1:length(u_solutions)
    u = u_solutions(i);
    sin_theta3 = 2 * u / (1 + u^2);
    cos_theta3 = (1 - u^2) / (1 + u^2);
    angle3 = atan2d(sin_theta3, cos_theta3);
    
    % Solve for theta2
    X = vpa((subs(-k2 * sind(app.Alpha1FieldEditField.Value),theta(3),angle3)));
    Y = vpa((subs(k1 * sind(app.Alpha1FieldEditField.Value),theta(3),angle3)));
    Z = vpa((subs(pz_eq - k4,theta(3),angle3)));
    % Solve for theta2 using tangent half-angle substitution
    syms u2
    theta2_solutions = solve((X+Z)*u2^2 - 2*Y*u2 + (Z-X) == 0, u2);
    
   for j = 1:length(theta2_solutions)
    u2 = theta2_solutions(j);

    sin_theta2 = 2 * u2 / (1 + u2^2);
    cos_theta2 = (1 - u2^2) / (1 + u2^2);
    angle2 = atan2d(sin_theta2, cos_theta2);
    
    % Solve for theta1 using px and py
    g1 = subs(app.A1FieldEditField.Value + f1 * cosd(theta(2)) - f2 * sind(theta(2)), [theta(2), theta(3)], [angle2, angle3]);
    g2 = subs(f2 * cosd(app.Alpha1FieldEditField.Value) * cosd(theta(2)) - f3 * sind(app.Alpha1FieldEditField.Value) ...
        - app.D2FieldEditField.Value * sind(app.Alpha1FieldEditField.Value) + f1 * cosd(app.Alpha1FieldEditField.Value) * sind(theta(2)), ...
        [theta(2), theta(3)], [angle2, angle3]);

    sin_theta1 = (-px_eq * g2 + py_eq * g1) / (g1^2 + g2^2);
    cos_theta1 = (py_eq * g2 + px_eq * g1) / (g1^2 + g2^2);
    angle1 = atan2d(sin_theta1, cos_theta1);
    
    inverseOrientation(angle1, angle2, angle3, alpha, T, i, j);
   end
end
elseif (app.Alpha1FieldEditField.Value == 0)
    A = cosd(app.Alpha1FieldEditField.Value) * (app.D2FieldEditField.Value + app.D3FieldEditField.Value * cosd(app.Alpha2FieldEditField.Value) ...
        + app.D4FieldEditField.Value * cosd(app.Alpha2FieldEditField.Value) * cosd(app.Alpha3FieldEditField.Value) ...
        + app.D4FieldEditField.Value * sind(app.Alpha2FieldEditField.Value) * sind(app.Alpha3FieldEditField.Value)) - pz_eq;

B = 2 * app.A3FieldEditField.Value * cosd(app.Alpha1FieldEditField.Value) * sind(app.Alpha2FieldEditField.Value);

C = cosd(app.Alpha1FieldEditField.Value) * (app.D2FieldEditField.Value + app.D3FieldEditField.Value * cosd(app.Alpha2FieldEditField.Value) ...
        + app.D4FieldEditField.Value * cosd(app.Alpha2FieldEditField.Value) * cosd(app.Alpha3FieldEditField.Value) ...
        - app.D4FieldEditField.Value * sind(app.Alpha2FieldEditField.Value) * sind(app.Alpha3FieldEditField.Value)) - pz_eq;

    % Define the quadratic equation
    quadratic_eq = (u^2) * A + u * B + C == 0;

    % Solve the quadratic equation
    u_solutions = solve(quadratic_eq, u);
    
    for i = 1:length(u_solutions)
        u = u_solutions(i);

        sin_theta3 = 2 * u / (1 + u^2);
        cos_theta3 = (1 - u^2) / (1 + u^2);
        angle3 = atan2d(sin_theta3, cos_theta3);
        
        % Solve for theta2
        X = vpa((subs(k1 * 2 * app.A1FieldEditField.Value, theta(3), angle3)));
        Y = vpa((subs(k2 * 2 * app.A1FieldEditField.Value, theta(3), angle3)));
        Z = vpa((subs(px_eq^2 + py_eq^2 + pz_eq^2 - k3, theta(3), angle3)));

        % Solve for theta2 using tangent half-angle substitution
        syms u2
        theta2_solutions = solve((X + Z) * u2^2 - 2 * Y * u2 + (Z - X) == 0, u2);

        for j = 1:length(theta2_solutions)
            u2 = theta2_solutions(j);

            sin_theta2 = 2 * u2 / (1 + u2^2);
            cos_theta2 = (1 - u2^2) / (1 + u2^2);
            angle2 = atan2d(sin_theta2, cos_theta2);

            % Solve for theta1 using px and py
            g1 = subs(app.A1FieldEditField.Value + f1 * cosd(theta(2)) - f2 * sind(theta(2)), [theta(2), theta(3)], [angle2, angle3]);
             g2 = subs(f2 * cosd(app.Alpha1FieldEditField.Value) * cosd(theta(2)) - f3 * sind(app.Alpha1FieldEditField.Value) ...
        - app.D2FieldEditField.Value * sind(app.Alpha1FieldEditField.Value) + f1 * cosd(app.Alpha1FieldEditField.Value) * sind(theta(2)), ...
        [theta(2), theta(3)], [angle2, angle3]);

            sin_theta1 = (-px_eq * g2 + py_eq * g1) / (g1^2 + g2^2);
            cos_theta1 = (py_eq * g2 + px_eq * g1) / (g1^2 + g2^2);
            angle1 = atan2d(sin_theta1, cos_theta1);
            
            inverseOrientation(angle1, angle2, angle3, alpha, T, i, j);
        end
    end
end
function [] = inverseOrientation(angle1,angle2,angle3,alpha,T,i,j)
        R03= eye(3);
        
        angles = [angle1,angle2,angle3];
        for k = 1:3
   Rk = [
        cosd(angles(k)), -sind(angles(k)), 0;
        sind(angles(k)) * cos(alpha(k)), cosd(angles(k)) * cos(alpha(k)), -sin(alpha(k));
        sind(angles(k)) * sin(alpha(k)), sin(alpha(k)) * cosd(angles(k)), cos(alpha(k));
        
    ];
    R03 = R03 * Rk;
        end
    R_des = T(1:3,1:3);
    R36= R03' * R_des;
    
     % Compute angle_5
        angle_5_solutions = [atan2d(sqrt(R36(2,1)^2 + R36(2,2)^2), R36(2,3)),-atan2d(sqrt(R36(2,1)^2 + R36(2,2)^2), R36(2,3))];
            for w= 1:length(angle_5_solutions)
                angle_5 = angle_5_solutions(w);
                
                 % Compute angle_4
                angle_4 = atan2d(R36(3,3) / sind(angle_5), -R36(1,3) / sind(angle_5));

                % Compute angle_6
                angle_6 = atan2d(-R36(2,2) / sind(angle_5), R36(2,1) / sind(angle_5));
                 % convert angles to deg 
                  theta1_deg = double(vpa((angle1)));
                  theta2_deg = double(vpa((angle2)));
                  theta3_deg = double(vpa((angle3)));
                  theta4_deg = double(vpa((angle_4)));
                  theta5_deg = double(vpa((angle_5)));
                  theta6_deg =double( vpa( (angle_6)));
                    % Display the results
           result = sprintf(['Solution %d,%d,%d:\n', ...
                          'theta1: %.2f°, theta2: %.2f°, theta3: %.2f°, ' ...
                          'theta4: %.2f°, theta5: %.2f°, theta6: %.2f°\n'], ...
                          i, j, w, theta1_deg, theta2_deg, theta3_deg, ...
                          theta4_deg, theta5_deg, theta6_deg);
        app.DisplaytheTextArea.Value = [app.DisplaytheTextArea.Value; {result}];
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Theta1FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Theta1FieldEditFieldLabel.Text = 'Theta1Field';
            app.Theta1FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1FieldEditField.Value = -170.78;
            app.Theta2FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Theta2FieldEditFieldLabel.Text = {'Theta2Field'; ''};
            app.Theta2FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2FieldEditField.Value = -153.5;
            app.Theta3FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Theta3FieldEditFieldLabel.Text = 'Theta3Field';
            app.Theta3FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3FieldEditField.Value = 3.74;
            app.Theta4FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Theta4FieldEditFieldLabel.Text = 'Theta4Field';
            app.Theta4FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4FieldEditField.Value = 186;
            app.Theta5FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Theta5FieldEditFieldLabel.Text = 'Theta5Field';
            app.Theta5FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta5FieldEditField.Value = 30.9;
            app.Theta6FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Theta6FieldEditFieldLabel.Text = 'Theta6Field';
            app.Theta6FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta6FieldEditField.Value = 119.56;
            app.EnterthevaluesofthejointvariblesindegreeLabel = uilabel(app.UIFigure);
            app.EnterthevaluesofthejointvariblesindegreeLabel.Text = 'Enter the values of the joint varibles in degree';
            app.Alpha0FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Alpha0FieldEditFieldLabel.Text = 'Alpha0Field';
            app.Alpha0FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Alpha1FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Alpha1FieldEditFieldLabel.Text = 'Alpha1Field';
            app.Alpha1FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Alpha1FieldEditField.Value = -90;
            app.A1FieldEditFieldLabel = uilabel(app.UIFigure);
            app.A1FieldEditFieldLabel.Text = 'A1Field';
            app.A1FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Alpha2FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Alpha2FieldEditFieldLabel.Text = 'Alpha2Field';
            app.Alpha2FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.D3FieldEditFieldLabel = uilabel(app.UIFigure);
            app.D3FieldEditFieldLabel.Text = 'D3Field';
            app.D3FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.D3FieldEditField.Value = 2;
            app.A3FieldEditFieldLabel = uilabel(app.UIFigure);
            app.A3FieldEditFieldLabel.Text = 'A3Field';
            app.A3FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.A3FieldEditField.Value = 2;
            app.D5FieldEditFieldLabel = uilabel(app.UIFigure);
            app.D5FieldEditFieldLabel.Text = 'D5Field';
            app.D5FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Alpha5FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Alpha5FieldEditFieldLabel.Text = 'Alpha5Field';
            app.Alpha5FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Alpha5FieldEditField.Value = -90;
            app.D1FieldEditFieldLabel = uilabel(app.UIFigure);
            app.D1FieldEditFieldLabel.Text = 'D1Field';
            app.D1FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.A2FieldEditFieldLabel = uilabel(app.UIFigure);
            app.A2FieldEditFieldLabel.Text = 'A2Field';
            app.A2FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.A2FieldEditField.Value = 3;
            app.Alpha3FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Alpha3FieldEditFieldLabel.Text = 'Alpha3Field';
            app.Alpha3FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Alpha3FieldEditField.Value = -90;
            app.D4FieldEditFieldLabel = uilabel(app.UIFigure);
            app.D4FieldEditFieldLabel.Text = 'D4Field';
            app.D4FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.D4FieldEditField.Value = 2;
            app.Alpha4FieldEditFieldLabel = uilabel(app.UIFigure);
            app.Alpha4FieldEditFieldLabel.Text = 'Alpha4Field';
            app.Alpha4FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.Alpha4FieldEditField.Value = 90;
            app.D2FieldEditFieldLabel = uilabel(app.UIFigure);
            app.D2FieldEditFieldLabel.Text = 'D2Field';
            app.D2FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.A4FieldEditFieldLabel = uilabel(app.UIFigure);
            app.A4FieldEditFieldLabel.Text = 'A4Field';
            app.A4FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.A0FieldEditFieldLabel = uilabel(app.UIFigure);
            app.A0FieldEditFieldLabel.Text = 'A0Field';
            app.A0FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateForwardKinematicsButton = uibutton(app.UIFigure, 'push');
            app.CalculateForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateForwardKinematicsButtonPushed, true);
            app.CalculateForwardKinematicsButton.Text = 'Calculate Forward Kinematics';
            app.CalculateInverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.CalculateInverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateInverseKinematicsButtonPushed, true);
            app.CalculateInverseKinematicsButton.Text = 'Calculate Inverse Kinematics';
            app.DisplaytheTextAreaLabel = uilabel(app.UIFigure);
            app.DisplaytheTextAreaLabel.Text = 'Display the ';
            app.DisplaytheTextArea = uitextarea(app.UIFigure);
            app.A5FieldEditFieldLabel = uilabel(app.UIFigure);
            app.A5FieldEditFieldLabel.Text = 'A5Field';
            app.A5FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterthevaluesoftheDHparametersanglesindegreeLabel = uilabel(app.UIFigure);
            app.EnterthevaluesoftheDHparametersanglesindegreeLabel.Text = 'Enter the values of the DH parameters (angles in degree)';
            app.D6FieldEditFieldLabel = uilabel(app.UIFigure);
            app.D6FieldEditFieldLabel.Text = 'D6Field';
            app.D6FieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = piper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end