classdef task1F < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Task2Tab                     matlab.ui.container.Tab
        AnglexBEditField             matlab.ui.control.NumericEditField
        AnglexBLabel                 matlab.ui.control.Label
        AngleyBEditField             matlab.ui.control.NumericEditField
        AngleyBLabel                 matlab.ui.control.Label
        AnglezBEditField             matlab.ui.control.NumericEditField
        AnglezBLabel                 matlab.ui.control.Label
        Task3Tab                     matlab.ui.container.Tab
        TransformationDropdown       matlab.ui.control.DropDown
        TransformationDropdownLabel  matlab.ui.control.Label
        EulerSequenceDropDown        matlab.ui.control.DropDown
        EulerSequenceDropDownLabel   matlab.ui.control.Label
        TranslationZEditField        matlab.ui.control.NumericEditField
        TranslationZLabel            matlab.ui.control.Label
        TranslationYEditField        matlab.ui.control.NumericEditField
        TranslationYLabel            matlab.ui.control.Label
        TranslationXEditField        matlab.ui.control.NumericEditField
        TranslationXLabel            matlab.ui.control.Label
        AnglexEditField              matlab.ui.control.NumericEditField
        AnglexLabel                  matlab.ui.control.Label
        AngleyEditField              matlab.ui.control.NumericEditField
        AngleyLabel                  matlab.ui.control.Label
        AnglezEditField              matlab.ui.control.NumericEditField
        AnglezLabel                  matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        ZLabel                       matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YLabel                       matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XLabel                       matlab.ui.control.Label
        OutputTextArea               matlab.ui.control.TextArea
        OutputTextArea_2Label        matlab.ui.control.Label
        TaskDropDown                 matlab.ui.control.DropDown
        TaskDropDown_2Label          matlab.ui.control.Label
        ComputeButton                matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            % Ensure the properties are initialized before accessing them
    if isempty(app.XEditField) || isempty(app.YEditField) || isempty(app.ZEditField)
        return;
    end

    % Reset input fields when a new task is selected
    app.XEditField.Value = 0;
    app.YEditField.Value = 0;
    app.ZEditField.Value = 0;
    app.AnglezEditField.Value = 0;
    app.AngleyEditField.Value = 0;
    app.AnglexEditField.Value = 0;
    app.TranslationXEditField.Value = 0;
    app.TranslationYEditField.Value = 0;
    app.TranslationZEditField.Value = 0;

    % Update the Output Text Area with the selected task
    selectedTask = app.TaskDropDown.Value;
    app.OutputTextArea.Value = ['Task selected: ', selectedTask, ...
        '. Enter inputs and click Compute.'];
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
             % Get the selected task
            selectedTask = app.TaskDropDown.Value;
            selectedOption = app.TransformationDropdown.Value;
            % Retrieve user inputs
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
            theta1 = app.AnglezEditField.Value;
            theta2 = app.AngleyEditField.Value;
            theta3 = app.AnglexEditField.Value;
            thetaB1 = app.AnglezBEditField.Value;
            thetaB2 = app.AngleyBEditField.Value;
            thetaB3 = app.AnglexBEditField.Value;
            tx = app.TranslationXEditField.Value;
            ty = app.TranslationYEditField.Value;
            tz = app.TranslationZEditField.Value;

            % Define rotation matrices
            Rx =  [1, 0, 0; 0, cosd(theta3), -sind(theta3); 0, sind(theta3), cosd(theta3)];
            Ry =  [cosd(theta2), 0, sind(theta2); 0, 1, 0; -sind(theta2), 0, cosd(theta2)];
            Rz =  [cosd(theta1), -sind(theta1), 0; sind(theta1), cosd(theta1), 0; 0, 0, 1];

            % Define the point in space
            P = [x; y; z];
            output = ''; % Initialize output string


function formattedMatrix = formatMatrix(matrix)
    formattedMatrix = '';
    for i = 1:size(matrix, 1)
        formattedMatrix = sprintf('%s%s\n', formattedMatrix, sprintf('%10.4f', matrix(i, :)));
    end
end
            % Perform computations based on the selected task
            switch selectedTask
                case 'Task 1: Rotations of a Point'
                    % Compute rotations
                  
            Rx =  [1, 0, 0; 0, cosd(theta3), -sind(theta3); 0, sind(theta3), cosd(theta3)];
            Ry =  [cosd(theta2), 0, sind(theta2); 0, 1, 0; -sind(theta2), 0, cosd(theta2)];
            Rz =  [cosd(theta1), -sind(theta1), 0; sind(theta1), cosd(theta1), 0; 0, 0, 1];
                    
                    P_rot_xy = Rz * P;

                   
                    P_rot_xz = Ry * P;

                    
                    P_rot_yz = Rx * P;
                    % Display results
                    output = sprintf(['--- Task 1: Rotations of a Point ---\n' ...
                        'Original Point: [%.2f, %.2f, %.2f]\n' ...
                        'Rotation in XY Plane (Z-axis): [%.2f, %.2f, %.2f]\n' ...
                        'Rotation in XZ Plane (Y-axis): [%.2f, %.2f, %.2f]\n' ...
                        'Rotation in YZ Plane (X-axis): [%.2f, %.2f, %.2f]\n'], ...
                        P(1), P(2), P(3), ...
                        P_rot_xy(1), P_rot_xy(2), P_rot_xy(3), ...
                        P_rot_xz(1), P_rot_xz(2), P_rot_xz(3), ...
                        P_rot_yz(1), P_rot_yz(2), P_rot_yz(3));

                case 'Task 2: Frame Rotation'
                  
    % Define the rotation matrices for frames A and B
    RzA = [cos(theta1), -sin(theta1), 0; sin(theta1), cos(theta1), 0; 0, 0, 1];
    RyA = [cos(theta2), 0, sin(theta2); 0, 1, 0; -sin(theta2), 0, cos(theta2)];
    RxA = [1, 0, 0; 0, cos(theta3), -sin(theta3); 0, sin(theta3), cos(theta3)];
    R_A = RzA * RyA * RxA;  % Rotation matrix for frame A
    
    RzB = [cos(thetaB1), -sin(thetaB1), 0; sin(thetaB1), cos(thetaB1), 0; 0, 0, 1];
    RyB = [cos(thetaB2), 0, sin(thetaB2); 0, 1, 0; -sin(thetaB2), 0, cos(thetaB2)];
    RxB = [1, 0, 0; 0, cos(thetaB3), -sin(thetaB3); 0, sin(thetaB3), cos(thetaB3)];
    R_B = RzB * RyB * RxB;  % Rotation matrix for frame B
    
    % Relative rotation matrix (A relative to B)
    R_rel = R_B' * R_A;  % Transpose of R_B gives the inverse (since it's an orthogonal matrix)
    
    % Output the result
    output = sprintf(['--- Task 2: Frame Rotation ---\n' ...
        'Rotation Matrices:\n' ...
        'Rotation Matrix for Frame A:\n%s\n' ...
        'Rotation Matrix for Frame B:\n%s\n' ...
        'Relative Rotation Matrix (A relative to B):\n%s'], ...
        formatMatrix(R_A), ...
        formatMatrix(R_B), ...
        formatMatrix(R_rel));
    
  
                case 'Task 3: Euler Angles'
                     % Get the selected Euler angle sequence from the dropdown
        
    if strcmp(selectedOption, 'Forward')
    sequence = app.EulerSequenceDropDown.Value;  % e.g., 'XYZ'
    
    % Define the individual rotation matrices
    Rx = @(theta) [1, 0, 0; 0, cosd(theta), -sind(theta); 0, sind(theta), cosd(theta)];
    Ry = @(theta) [cosd(theta), 0, sind(theta); 0, 1, 0; -sind(theta), 0, cosd(theta)];
    Rz = @(theta) [cosd(theta), -sind(theta), 0; sind(theta), cosd(theta), 0; 0, 0, 1];
    
    % Initialize total rotation matrix
    R_total = eye(3);
    angles = [theta1, theta2, theta3];  % Input angles from edit fields
    
    % Compute the rotation matrix based on the sequence
    for i = 1:3
        switch sequence(i)
            case 'Z'
                R = Rz(angles(i));
            case 'Y'
                R = Ry(angles(i));
            case 'X'
                R = Rx(angles(i));
        end
        R_total = R_total * R;  % Multiply matrices in order
    end
    
    % Apply the transformation to the input point
    P_transformed = R_total * P;
    
    % Display results
    output = sprintf(['--- Task 3: Euler Angles ---\n' ...
        'Selected Sequence: %s\n' ...
        'Transformed Point: [%.2f, %.2f, %.2f]\n'], ...
        sequence, P_transformed(1), P_transformed(2), P_transformed(3));
    else 
            sequence = app.EulerSequenceDropDown.Value;  % e.g., 'XYZ'

% Define the individual rotation matrices (with negative angles for inverse)
Rx = @(theta) [1, 0, 0; 0, cosd(theta), -sind(theta); 0, sind(theta), cosd(theta)];
Ry = @(theta) [cosd(theta), 0, sind(theta); 0, 1, 0; -sind(theta), 0, cosd(theta)];
Rz = @(theta) [cosd(theta), -sind(theta), 0; sind(theta), cosd(theta), 0; 0, 0, 1];

% Initialize total rotation matrix for the inverse
R_inverse_total = eye(3);
angles = [theta1, theta2, theta3];  % Input angles from edit fields

% Compute the inverse rotation matrix based on the reversed sequence
for i = 3:-1:1  % Reverse the order of the sequence
    switch sequence(i)
        case 'Z'
            R = Rz(-angles(i));  % Use negative angle
        case 'Y'
            R = Ry(-angles(i));  % Use negative angle
        case 'X'
            R = Rx(-angles(i));   % Use negative angle
    end
    R_inverse_total = R * R_inverse_total;  % Multiply matrices in reverse order
end

% Apply the inverse transformation to the transformed point
P_original = R_inverse_total * P;

% Display results
output = sprintf(['--- Task 3: Euler Angles (Inverse) ---\n' ...
    'Selected Sequence: %s\n' ...
    'Original Point: [%.2f, %.2f, %.2f]\n'], ...
    sequence, P_original(1), P_original(2), P_original(3));
    end
    
      
                case 'Task 4: Transformation of a Point'
                    % Transformation using translation and rotation
                  
            Rz =  [cosd(theta1), -sind(theta1), 0; sind(theta1), cosd(theta1), 0; 0, 0, 1];
                    R_transform = Rz;
                    T = [R_transform, [tx; ty; tz]; 0, 0, 0, 1];
                    P_h = [P; 1];
                    P_transformed = T * P_h;

                    % Display results
                    output = sprintf(['--- Task 4: Transformation of a Point ---\n' ...
                        'Original Point: [%.2f, %.2f, %.2f]\n' ...
                        'Transformed Point: [%.2f, %.2f, %.2f]\n'], ...
                        P(1), P(2), P(3), ...
                        P_transformed(1), P_transformed(2), P_transformed(3));

                case 'Task 5: Reference Frame Change'
                     % Transformation from one reference frame to another
                       Rx =  [1, 0, 0; 0, cosd(theta3), -sind(theta3); 0, sind(theta3), cosd(theta3)];
                       Ry =  [cosd(theta2), 0, sind(theta2); 0, 1, 0; -sind(theta2), 0, cosd(theta2)];
                       Rz =  [cosd(theta1), -sind(theta1), 0; sind(theta1), cosd(theta1), 0; 0, 0, 1];
            R_ref = Rz * Ry * Rx; % Composite rotation
            T_ref = [R_ref, [tx; ty; tz]; 0, 0, 0, 1];   % Transformation matrix
            P_h = [P; 1];                               % Homogeneous coordinates
            P_transformed = T_ref * P_h;                % Transform the point

            % Display results
            output = sprintf(['--- Task 5: Reference Frame Change ---\n' ...
                'Original Point: [%.2f, %.2f, %.2f]\n' ...
                'Transformed Point: [%.2f, %.2f, %.2f]\n'], ...
                P(1), P(2), P(3), ...
                P_transformed(1), P_transformed(2), P_transformed(3));

                case 'Task 6: Inverse Transformation'
                    % Inverse of a transformation matrix
                       Rz =  [cosd(theta1), -sind(theta1), 0; sind(theta1), cosd(theta1), 0; 0, 0, 1];
            R_transform = Rz;
            T = [R_transform, [tx; ty; tz]; 0, 0, 0, 1];
            T_inverse = inv(T);

            % Display results
            output = sprintf(['--- Task 6: Inverse Transformation ---\n' ...
                'Transformation Matrix:\n%s\n' ...
                'Inverse Transformation Matrix:\n%s\n'], ...
                formatMatrix(T), formatMatrix(T_inverse));

                otherwise
                    output = 'Invalid task selected.';
            end

            % Display the results in the Output Text Area
            app.OutputTextArea.Value = output;
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'ComputeButton';
            app.TaskDropDown_2Label = uilabel(app.UIFigure);
            app.TaskDropDown_2Label.Text = 'TaskDropDown';
            app.TaskDropDown = uidropdown(app.UIFigure);
            app.TaskDropDown.Items = {'Task 1: Rotations of a Point', 'Task 2: Frame Rotation', 'Task 3: Euler Angles', 'Task 4: Transformation of a Point', 'Task 5: Reference Frame Change', 'Task 6: Inverse Transformation'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = 'Task 1: Rotations of a Point';
            app.OutputTextArea_2Label = uilabel(app.UIFigure);
            app.OutputTextArea_2Label.Text = ' OutputTextArea  ';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X   ';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y   ';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z   ';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnglezLabel = uilabel(app.UIFigure);
            app.AnglezLabel.Text = ' Anglez';
            app.AnglezEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleyLabel = uilabel(app.UIFigure);
            app.AngleyLabel.Text = 'Angley';
            app.AngleyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnglexLabel = uilabel(app.UIFigure);
            app.AnglexLabel.Text = 'Anglex';
            app.AnglexEditField = uieditfield(app.UIFigure, 'numeric');
            app.TranslationXLabel = uilabel(app.UIFigure);
            app.TranslationXLabel.Text = 'TranslationX';
            app.TranslationXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TranslationYLabel = uilabel(app.UIFigure);
            app.TranslationYLabel.Text = 'TranslationY';
            app.TranslationYEditField = uieditfield(app.UIFigure, 'numeric');
            app.TranslationZLabel = uilabel(app.UIFigure);
            app.TranslationZLabel.Text = 'TranslationZ';
            app.TranslationZEditField = uieditfield(app.UIFigure, 'numeric');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Task2Tab = uitab(app.TabGroup);
            app.Task2Tab.Title = 'Task2';
            app.AnglezBLabel = uilabel(app.Task2Tab);
            app.AnglezBLabel.Text = ' AnglezB';
            app.AnglezBEditField = uieditfield(app.Task2Tab, 'numeric');
            app.AngleyBLabel = uilabel(app.Task2Tab);
            app.AngleyBLabel.Text = 'AngleyB';
            app.AngleyBEditField = uieditfield(app.Task2Tab, 'numeric');
            app.AnglexBLabel = uilabel(app.Task2Tab);
            app.AnglexBLabel.Text = 'AnglexB';
            app.AnglexBEditField = uieditfield(app.Task2Tab, 'numeric');
            app.Task3Tab = uitab(app.TabGroup);
            app.Task3Tab.Title = 'Task3';
            app.EulerSequenceDropDownLabel = uilabel(app.Task3Tab);
            app.EulerSequenceDropDownLabel.Text = 'EulerSequenceDropDown';
            app.EulerSequenceDropDown = uidropdown(app.Task3Tab);
            app.EulerSequenceDropDown.Items = {'XYZ', 'XZY', 'XYX', 'XZX', 'YXZ', 'YZX', 'YXY', 'YZY', 'ZXY', 'ZYX', 'ZXZ', 'ZYZ'};
            app.EulerSequenceDropDown.Value = 'XYZ';
            app.TransformationDropdownLabel = uilabel(app.Task3Tab);
            app.TransformationDropdownLabel.Text = 'TransformationDropdown';
            app.TransformationDropdown = uidropdown(app.Task3Tab);
            app.TransformationDropdown.Items = {'Forward', 'Inverse'};
            app.TransformationDropdown.Value = 'Forward';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task1F

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end