classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DTextArea                     matlab.ui.control.TextArea
        DTextAreaLabel                matlab.ui.control.Label
        aTextArea                     matlab.ui.control.TextArea
        aTextAreaLabel                matlab.ui.control.Label
        jointnumbersEditField         matlab.ui.control.NumericEditField
        jointnumbersEditFieldLabel    matlab.ui.control.Label
        alphaTextArea                 matlab.ui.control.TextArea
        alphaTextAreaLabel            matlab.ui.control.Label
        thegeneratedpolynomialforeachthetaconsequentlyLabel  matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        UITable5                      matlab.ui.control.Table
        ifthereisnojointkeepitzeroLabel  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        pointsanditscorespondingtimeafteroptimizationLabel  matlab.ui.control.Label
        UITable4                      matlab.ui.control.Table
        pointsrangeEditField          matlab.ui.control.NumericEditField
        pointsrangeEditFieldLabel     matlab.ui.control.Label
        leastdisplacementsolutionforeverypointjointsiscolumnLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        UITable3                      matlab.ui.control.Table
        orderofsplineEditField        matlab.ui.control.NumericEditField
        orderofsplineEditFieldLabel   matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        finalTimeforsinpathEditField  matlab.ui.control.NumericEditField
        finalTimeforsinpathEditFieldLabel  matlab.ui.control.Label
        submitButton                  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
  

    end
    

    methods (Access = public)


   %1,2 function that will compute the endefector path ,we will use sine wave 
   function y = end_effector_path(app,final_time, point_range)

Endeffector_mat_intialpos = eye(4);
Endeffector_mat_intialpos(:,4) = [1 , 1 , 1 , 1];
sampling_step = final_time / (point_range - 1);
sampled_time = 0:sampling_step:final_time;

endeffect_list = cell(1, point_range);
endeffect_list{1} = Endeffector_mat_intialpos;

for i = 2:point_range
    endeffect_list{i} = Endeffector_mat_intialpos * sin(sampled_time(i));
     endeffect_list{i}(1:3,1:3) = eye(3);
    endeffect_list{i}(4,:) = [0 0 0 1];
end

y = endeffect_list;
end
%-----------------------------------------

function p = inversekinematicsButtonPushed(app, tdesired)


    % Step (a) Define DH parameters and forward kinematics
    n = (app.jointnumbersEditField.Value);  % Number of joints
    
    % Define joint variables dynamically
    q = sym('q', [1 n]);  % Creates a symbolic row vector for joint variables
    
    alpha = app.alphaTextArea.Value;
    alpha = deg2rad(str2num(alpha{1}));  % Convert to radians
    a = str2num(app.aTextArea.Value{1});
    d = str2num(app.DTextArea.Value{1});
    
    % Define transformation matrices based on DH parameters
    T = eye(4); % Start with identity matrix
    for i = 1:n
        T = T * dh_to_transform(alpha(i), d(i), a(i), q(i));
    end
    
    % Compute forward kinematics
    T_final = simplify(T);
    
    % Step (a) Find desired configuration matrix {Tdes}
    
    T_des = tdesired;
    
    % Initialize output string for the app's text area
    outputString = "Inverse Kinematics Results:\n";
    
    % Step (b) Newton-Raphson method to find multiple solutions
    initial_guesses = [
        0.1*ones(1, n);
        -0.1*ones(1, n);
        pi/4*ones(1, n);
        -pi/4*ones(1, n);
        zeros(1, n);
        0.2*ones(1, n);
        -0.2*ones(1, n);
        ones(1, n);
        -ones(1, n);
        2*ones(1, n);
        -2*ones(1, n)
    ]; % Add more initial guesses as needed
    
    solutions_square = []; % Store all solutions
    solutions_rectangular = [];
    
    % Define the error function
    T_finalE = T_final(1:3, 1:4);
    T_desE = T_des(1:3, 1:4);
    error_func = @(k) reshape(double(subs(T_finalE - T_desE, q, k)), [], 1);
    
    % Compute the Jacobian matrix
    J = jacobian([reshape(T_final(1:3, 1:3), [], 1); T_final(1:3, 4)], q);
    
    % % Square Jacobian Method
    % outputString = outputString + "Square Jacobian Method:\n";
    % for guess_idx = 1:size(initial_guesses, 1)
    %     q_initial = initial_guesses(guess_idx, :);
    %     q_solution_square = q_initial;
    %     for iter = 1:100  % Maximum iterations
    %         current_error_square = error_func(q_solution_square);
    %         current_J_square = double(subs(J, q, num2cell(q_solution_square)));
    %         if norm(current_error_square) < .00005
    %             outputString = outputString + sprintf("Converged for initial guess %d in %d iterations.\n", guess_idx, iter);
    %             break;
    %         end
    %         delta_q = -current_J_square \ current_error_square;
    %         q_solution_square = q_solution_square + delta_q';
    %     end
    %     if iter < 100 && all(abs(current_error_square) < 1e-6)
    %         is_unique = true;
    %         for s = 1:size(solutions_square, 1)
    %             if norm(mod(solutions_square(s, :), 6.28) - mod(q_solution_square, 6.28)) < 0.1
    %                 is_unique = false;
    %                 break;
    %             end
    %         end
    %         if is_unique
    %             solutions_square = [solutions_square; q_solution_square]; %#ok<AGROW>
    %         end
    %     end
    % end
    
 %   solutions_deg_square = rad2deg(solutions_square);
    
    
    % Rectangular Jacobian Method
    outputString = outputString + "Rectangular Jacobian Method:\n";
    for guess_idx = 1:size(initial_guesses, 1)
        q_initial = initial_guesses(guess_idx, :);
        q_solution_rectangular = q_initial;
        for iter = 1:100
            current_error_rectangular = error_func(q_solution_rectangular);
            current_J_rectangular = double(subs(J, q, num2cell(q_solution_rectangular)));
            if norm(current_error_rectangular) < .00005
                outputString = outputString + sprintf("Converged for initial guess %d in %d iterations.\n", guess_idx, iter);
                break;
            end
            delta_q = -(current_J_rectangular' * current_J_rectangular) \ (current_J_rectangular' * current_error_rectangular);
            q_solution_rectangular = q_solution_rectangular + delta_q';
        end
        if iter < 100 && all(abs(current_error_rectangular) < 1e-6)
            is_unique = true;
            for s = 1:size(solutions_rectangular, 1)
                if norm(mod(solutions_rectangular(s, :), 6.28) - mod(q_solution_rectangular, 6.28)) < 0.1
                    is_unique = false;
                    break;
                end
            end
            if is_unique
                solutions_rectangular = [solutions_rectangular; q_solution_rectangular]; %#ok<AGROW>
            end
        end
    end
    
    solutions_deg_rectangular = rad2deg(solutions_rectangular);
      solutions_deg_rectangular = mod(solutions_deg_rectangular,360);
   solutions_deg_rectangular = transpose(solutions_rectangular);
    p = solutions_deg_rectangular;
    % Display results in the text area


function T = dh_to_transform(alpha, d, a, theta)
    T = [
        cos(theta), -sin(theta), 0, a;
        sin(theta) * cos(alpha), cos(theta) * cos(alpha), -sin(alpha), -sin(alpha)*d;
        sin(theta) * sin(alpha), sin(alpha) * cos(theta), cos(alpha),cos(alpha)* d;
        0, 0, 0, 1
    ];
end
  end


%3-inverse kinamatic functoin of endeffectlist ---------
function t = inverse_kine_end_list(app,endeffect_path_list)
inverseforeveryTdes = 0;
inverseforeveryTdes = cell(1,width(endeffect_path_list));
for i = 1:width(endeffect_path_list)
    inverseforeveryTdes{i} = app.inversekinematicsButtonPushed(endeffect_path_list{i});
end
t = inverseforeveryTdes;
end
%--------------------------------------------------

%4, 5- outside joint limit function-------------------------------------------
function y1 = JointLimits (app,inverse_kine_matrix_list,jointlimitsRight,jointlimitsLeft) 
correct_joint=cell(1,width(inverse_kine_matrix_list))
    for i=1:width(inverse_kine_matrix_list)
        for j=1:width(inverse_kine_matrix_list{i})
           if (inverse_kine_matrix_list{i}(:,j)<= jointlimitsRight & inverse_kine_matrix_list{i}(:,j)>= jointlimitsLeft)
                correct_joint{i}(:,j) = inverse_kine_matrix_list{i}(:,j);

            end
        end
    end
    correct_joint = correct_joint(~cellfun('isempty', correct_joint));
y1 = correct_joint ;

end

%------------------------------------------------------------------y1=JointLimits([30 30 -30 -30; 20 50 120 60 ; 0 50 90 70 ; 70 4 15 9],jointlimitsRight,jointlimitsLeft)

% 6,7 minimum dispalcement , and print it as a table

function y1 = minimum_path(app,correct_joint_list)
m=[];

    for i =1:width(correct_joint_list)-1
       
        for j=1:height(correct_joint_list{1}) 
            x = min(abs(correct_joint_list{i+1}(j,1)-correct_joint_list{i}(j,1)));
            for k1=1: width(correct_joint_list{i})
                for k2 = 1:width(correct_joint_list{i+1})
                if( (min(abs(correct_joint_list{i+1}(j,k2)-correct_joint_list{i}(j,k1)))) <= x  )
                    x= min(abs(correct_joint_list{i+1}(j,k2)-correct_joint_list{i}(j,k1)));
                    m(j,i+1)=correct_joint_list{i+1}(j,k2);
                   m(j,i)= correct_joint_list{i}(j,k1);
                end
            end
        end
    end
    end
    y1=m;
end
%-------------------------------------------------------------------------
%----------------------------------------------------------------
%-------------------------------------------------------------------

function y1 = assign_time(app,correct_matrix)

global final_time_frame ;
global delta_time_frame;
 delta_time_frame = final_time_frame/(width(correct_matrix)-1);
assigned_correct_matrix= correct_matrix;
for i=1:width(correct_matrix)
assigned_correct_matrix(height(correct_matrix)+1,i)=(i-1)*delta_time_frame;
end
y1 = assigned_correct_matrix;
end
%-------------------------------------------------------------

%1 compute velocity and acceleratio
function [v,a] = v_and_a (app,assigned_matrix,delta_time)
assigned_matrix1 = cell(1,width(assigned_matrix));
assigned_matrix1 = assigned_matrix;

for i=1:height(assigned_matrix1)-1
for j=1:width(assigned_matrix1)-1
    v(i,j) = (assigned_matrix1(i,j+1)-assigned_matrix1(i,j))/delta_time;
end
end
v(:,width(assigned_matrix1)) = 0;

for i=1:height(v)
for j=1:width(v)-1
    a(i,j) = (v(i,j+1)-v(i,j))/delta_time;
end
end
a(:,width(v)) =0;

end
%-----------------------------

%2,3,4,5 check if it in speed and acceleration limit

function is_in_limit = v_a_limits(app,velo,acc)
global speed_limit,global acceleration_limit;
if (abs(velo)<speed_limit |abs (acc)<acceleration_limit)
 is_in_limit = 1 ;
else
  is_in_limit=0 ;
end

end

function [] = multipling_time(app,input)
global final_time_frame;
if(input==0)
final_time_frame = final_time_frame*1.1;
else
final_time_frame = final_time_frame*0.9;
end


end
    %-----------------------------------------------------
 function times = generate_splines(app,matrix, m)
global polynomial_cell_array;

times = matrix(end, :);
times = transpose(times);

for r = 1:height(matrix) - 1
    points = matrix(r, :);
    points = transpose(points);

    nOfp = height(points);
    n = nOfp - 1;

    syms t;
    ts = t.^(0:m);  % Create powers of t for polynomial basis

    syms a [n m+1];
    matrix_polynomial = a * ts.';  % Polynomial matrix for each segment

    % Define equations for the constraints
    eqn = [];

    % Left boundary points
    for i = 1:n
        eqn = [eqn, subs(matrix_polynomial(i), t, times(i)) == points(i)];
    end

    % Right boundary points
    for i = 1:n
        eqn = [eqn, subs(matrix_polynomial(i), t, times(i + 1)) == points(i + 1)];
    end

    % Derivative continuity
    for k = 1:m-1
        for i = 1:n-1
            eqn = [eqn, subs(diff(matrix_polynomial(i), t, k), t, times(i + 1)) == ...
                       subs(diff(matrix_polynomial(i + 1), t, k), t, times(i + 1))];
        end
    end

    % Additional boundary conditions
    if m >= 2
        eqn = [eqn, subs(diff(matrix_polynomial(1), t, 1), t, times(1)) == 0];
    end
    if m >= 3
        eqn = [eqn, subs(diff(matrix_polynomial(end), t, 1), t, times(end)) == 0];
    end
    if m >= 4
        eqn = [eqn, subs(diff(matrix_polynomial(1), t, 2), t, times(1)) == 0];
    end
    if m >= 5
        eqn = [eqn, subs(diff(matrix_polynomial(end), t, 2), t, times(end)) == 0];
    end

    % Solve for coefficients
    spline_coefficients = solve(eqn, a);
    p = double(struct2array(spline_coefficients));
    
    % Store coefficients
    for i = 1:m+1
        polynomial_cell_array{r}(:, i) = p((i-1)*n+1:i*n);
        
    end  
    %flip
polynomial_cell_array{r} = fliplr(polynomial_cell_array{r});

    % Plot splines and points
    for i = 1:height(polynomial_cell_array{r})
        Tm = linspace(times(i), times(i + 1), 500);
        value = polyval((polynomial_cell_array{r}(i, :)), Tm);
        plot(Tm, value);
        hold on;
    end

    for i = 1:n+1
        plot(times(i), points(i), 'o');
    end
end

 end

 function [y1, y2] = direvativespline(app,polynomial_cell_array)
    y1 = cell(1, width(polynomial_cell_array));
    y2 = cell(1, width(polynomial_cell_array));

    for j = 1:width(polynomial_cell_array)
        n = height(polynomial_cell_array{j});
        y1{j} = zeros(n, size(polynomial_cell_array{j}, 2) - 1);
        y2{j} = zeros(n, size(polynomial_cell_array{j}, 2) - 2);

        for i = 1:n
            y1{j}(i, :) = polyder(polynomial_cell_array{j}(i, :));
            y2{j}(i, :) = polyder(y1{j}(i, :));
        end
    end
end

function q1 = checker_of_deri(y1,y2,times,speed_limit,acc_limit)
global final_time_frame ;
for k=1:width(y1)
time = times(k)
h=linspace(time,times(k+1),100)

for i=1:height(y1{k})

if   polyval(y1{k}(i,:),h) > speed_limit(k)
    final_time_frame = final_time_frame*1.1;
    break

    if   polyval(y1{k}(i,:),h) < speed_limit(k)
    final_time_frame = final_time_frame*.9;
    break
end
end
end
for  i=1:height(y1{k})
    if   polyval(y2{k}(i,:),h) > acc_limit(k)
    final_time_frame = final_time_frame*1.1;
    break
    end

     if   polyval(y2{k}(i,:),h) < acc_limit(k)
    final_time_frame = final_time_frame*0.9;
    break
    end
end


end
end

function populateTablesFromCellArray(app, cellArray)
    % Function to populate App Designer UITable with rows of matrices from a cell array
    % app: The App Designer app object (provides access to the UIFigure)
    % cellArray: A cell array where each component is a matrix to be displayed in a table
    
    % Ensure the App contains a UITable component named 'UITable'
    
    % Initialize a combined table to hold all data
    allData = [];
    allRowNames = [];
    rowOffset = 0; % Offset for naming rows correctly
    
    % Loop through each matrix in the cell array
    for i = 1:length(cellArray)
        matrix = cellArray{i}; % Get the current matrix
        
        % Append the data from this matrix to the combined table
        allData = [allData; matrix];
        
        % Generate row names for this matrix (e.g., Theta 1, Theta 2, ...)
        numRows = size(matrix, 1);
        
        
        % Append these row names to the combined list of row names
    
        
        % Update the row offset
        rowOffset = rowOffset + numRows;
    end
    
    % Populate the UITable in App Designer
    app.UITable5.Data = allData;
    
    % Set row names
    app.UITable5.RowName = allRowNames;
end


    end
%-----------------------------------------------------
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            m = [0 0 ;0 0; 0 0;0 0 ; 0 0; 0 0];
            app.UITable.Data= m;
            app.UITable2.Data = m;
         global  speed_limit ;            %speed_limit = [ 5 ;5 ; 5]; %input
global acceleration_limit;              %acceleration_limit=[1;1;1];%input
global final_time_frame ;
final_time_frame = 5;
global delta_time_frame;                % delta_time_frame =0;
    global polynomial_cell_array;
polynomial_cell_array=[];
        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)
          jointlimittable=  app.UITable.Data ;
         accvelolimittable = app.UITable2.Data;
         
global acceleration_limit;
global speed_limit;
global final_time_frame;
global delta_time_frame;
    global polynomial_cell_array;
    hold off
          joint_limit_min = jointlimittable(1:find(jointlimittable(:,1) ~= 0, 1, 'last'), 1);
          joint_limit_max = jointlimittable(1:find(jointlimittable(:,2) ~= 0, 1, 'last'),2);
           speed_limit  = accvelolimittable(1:find(accvelolimittable(:,1) ~= 0, 1, 'last'),1);
          acceleration_limit = accvelolimittable(1:find(accvelolimittable(:,2) ~= 0, 1, 'last'),2);
          order_of_spline = app.orderofsplineEditField.Value;
     final_time  =   app.finalTimeforsinpathEditField.Value;
    point_range = app.pointsrangeEditField.Value;
    
    joint_limit_max = [200;200;200;200;200];
    joint_limit_min = [-100;-100;-100;-100;-100];
    acceleration_limit = [1;1;1;1;1];
    speed_limit = [5;5;5;5;5];
% A ----------------------------------------------------------------
   %  end_effector_path_list = app.end_effector_path(final_time,point_range);
 % IK_matrix_list =   app.inverse_kine_end_list(end_effector_path_list);
 % disp("-----")
 % disp(IK_matrix_list)
  IK_matrix_list = {[10 70 ;20 -20 ;-20 30;15 30;10 40],[30 30 ;20 50;0 50 ;70 4;10 40],[15 10;90 66; 0 70 ; 94 33;10 40]};
correct_joint_list =app.JointLimits (IK_matrix_list,joint_limit_max,joint_limit_min);
minimum_path_list=app.minimum_path(correct_joint_list);

app.UITable3.Data = minimum_path_list;

% B ----------------------------------------------------------------
 
assigned_matrix = app.assign_time(minimum_path_list);

% [velocity,accerlation] = app.v_and_a(assigned_matrix,delta_time_frame);
% 
% for i=1 :100
%     assigned_matrix=app.assign_time(minimum_path_list);
% [velocity,accerlation] = app.v_and_a(assigned_matrix,delta_time_frame);
% if i ~=100
%    app. multipling_time(app.v_a_limits(velocity,accerlation));
% end
% end
% 
% if(app.v_a_limits(velocity,accerlation))
% app.multipling_time(app.v_a_limits(velocity,accerlation));
% assigned_matrix=app.assign_time(minimum_path_list);
% [velocity,accerlation] =app. v_and_a(assigned_matrix,delta_time_frame);
% end


%code for data table 
app.UITable4.Data = assigned_matrix;
num_rows = size(app.UITable4.Data, 1);
row_names = cell(num_rows, 1);
for i = 1:num_rows-1
    row_names{i} = num2str(i); 
end
row_names{end} = 'Time'; % Name the last row

% Update the table with the new row names
app.UITable4.RowName = row_names;


% C -------------------------------------------------------------

tm = app.generate_splines(assigned_matrix,order_of_spline);
 [y1,y2] = app.direvativespline(polynomial_cell_array);
%there will be conflict if we aplly the derivative checker with the
%prevoius checker function because the t will always bounce between a range
%of point and will not converge
%________________________________________
app.populateTablesFromCellArray(polynomial_cell_array)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'minimum limit'; 'maximim limit'};
            app.UITable.ColumnEditable = [true true];
            app.submitButton = uibutton(app.UIFigure, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            app.finalTimeforsinpathEditFieldLabel = uilabel(app.UIFigure);
            app.finalTimeforsinpathEditFieldLabel.Text = 'final Time for sin path';
            app.finalTimeforsinpathEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'speed limit'; 'acceleration limit'};
            app.UITable2.ColumnEditable = true;
            app.orderofsplineEditFieldLabel = uilabel(app.UIFigure);
            app.orderofsplineEditFieldLabel.Text = 'order of spline';
            app.orderofsplineEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'1st point'; '2nd point'; '3rd point'; '4th point'; '5th point'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '_____________________________________________________________________________________________________________________________________________________________________________________________________';
            app.leastdisplacementsolutionforeverypointjointsiscolumnLabel = uilabel(app.UIFigure);
            app.leastdisplacementsolutionforeverypointjointsiscolumnLabel.Text = 'least displacement solution for every point (joints is column)';
            app.pointsrangeEditFieldLabel = uilabel(app.UIFigure);
            app.pointsrangeEditFieldLabel.Text = 'points range';
            app.pointsrangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable4 = uitable(app.UIFigure);
            app.UITable4.ColumnName = {'1st point'; '2nd point'; '3rd point'; '4th point'; '5th point'};
            app.UITable4.RowName = {};
            app.pointsanditscorespondingtimeafteroptimizationLabel = uilabel(app.UIFigure);
            app.pointsanditscorespondingtimeafteroptimizationLabel.Text = 'points and its coresponding time after optimization';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '* enter in the tables the the joint limits , speed and acceleration limits ,points range , final time and order of spline';
            app.ifthereisnojointkeepitzeroLabel = uilabel(app.UIFigure);
            app.ifthereisnojointkeepitzeroLabel.Text = '* if there is no joint keep it zero ';
            app.UITable5 = uitable(app.UIFigure);
            app.UITable5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable5.RowName = {};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '* if the joint limit of last link is equal to zero , put it a number close to zero eg(0.001)';
            app.thegeneratedpolynomialforeachthetaconsequentlyLabel = uilabel(app.UIFigure);
            app.thegeneratedpolynomialforeachthetaconsequentlyLabel.Text = 'the generated polynomial for each theta consequently';
            app.alphaTextAreaLabel = uilabel(app.UIFigure);
            app.alphaTextAreaLabel.Text = 'alpha';
            app.alphaTextArea = uitextarea(app.UIFigure);
            app.alphaTextArea.Value = {'[0 90 0 0 90]'};
            app.jointnumbersEditFieldLabel = uilabel(app.UIFigure);
            app.jointnumbersEditFieldLabel.Text = 'joint numbers';
            app.jointnumbersEditField = uieditfield(app.UIFigure, 'numeric');
            app.jointnumbersEditField.Value = 5;
            app.aTextAreaLabel = uilabel(app.UIFigure);
            app.aTextAreaLabel.Text = 'a';
            app.aTextArea = uitextarea(app.UIFigure);
            app.aTextArea.Value = {'[2.7 0 2 1.3 0]'};
            app.DTextAreaLabel = uilabel(app.UIFigure);
            app.DTextAreaLabel.Text = 'D';
            app.DTextArea = uitextarea(app.UIFigure);
            app.DTextArea.Value = {'[2.75 0 0 0 0]'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end